classdef CSIVitalSignMonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        FromFileTab                  matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        SelectFileandFilterParametersPanel  matlab.ui.container.Panel
        LoadfileEditFieldLabel       matlab.ui.control.Label
        loadFileField                matlab.ui.control.EditField
        browseButton                 matlab.ui.control.Button
        FilteringParametersLabel     matlab.ui.control.Label
        BandpassFilterLabel          matlab.ui.control.Label
        HampelFilterLabel            matlab.ui.control.Label
        SavitzkyGolayFilterLabel     matlab.ui.control.Label
        SampleFrequencyLabel         matlab.ui.control.Label
        sampleFrequencyField         matlab.ui.control.NumericEditField
        WindowLengthLabel            matlab.ui.control.Label
        windowLengthField            matlab.ui.control.NumericEditField
        OrderEditFieldLabel_2        matlab.ui.control.Label
        orderField                   matlab.ui.control.NumericEditField
        LowCutoffEditFieldLabel_2    matlab.ui.control.Label
        lowCutoffField               matlab.ui.control.NumericEditField
        HighCutoffLabel              matlab.ui.control.Label
        highCutoffField              matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel_2   matlab.ui.control.Label
        windowSizeField              matlab.ui.control.NumericEditField
        ThresholdLabel               matlab.ui.control.Label
        thresholdField               matlab.ui.control.NumericEditField
        PolynomialDegreeLabel        matlab.ui.control.Label
        degreeField                  matlab.ui.control.NumericEditField
        WindowSizeLabel              matlab.ui.control.Label
        windowSizeSGField            matlab.ui.control.NumericEditField
        GridLayout3                  matlab.ui.container.GridLayout
        processFileButton            matlab.ui.control.Button
        predictButton                matlab.ui.control.Button
        GridLayout4                  matlab.ui.container.GridLayout
        saveButton                   matlab.ui.control.Button
        RealTimeMonitorTab           matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
        BPMLabel                     matlab.ui.control.Label
        UniversidadAutnomadeBajaCaliforniaInstitutodeIngenieraLabel  matlab.ui.control.Label
        startServerButton            matlab.ui.control.Button
        ServerAddressEditFieldLabel  matlab.ui.control.Label
        serverField                  matlab.ui.control.EditField
        ServerStatusEditFieldLabel   matlab.ui.control.Label
        statusField                  matlab.ui.control.EditField
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        csiFile % CSI that is going to be loaded
        sampleFrequency % Sample frequency (in Hz) in which data from the file was collected 
        windowLength % Length of each window (in seconds) 
        fileType % DAT == 1, CSV == 2
        bandpassOrder % Order of the bandpass filter
        lowCutOff % Low cut-off frequency of the bandpass filter
        highCutOff % High cut-off frequency of the bandpass filter
        hampelSize % Size of the window for hampel filter
        hampelThreshold % Threshold for the hampel filter
        polDegree % Polynomial degree for the SG filter
        sgSize % Size of the window for SG filter
        dataCalibrator % Calibrator class object
        featureExtractor % FeatureExtractor class object
        dataset % Dataset that contains features obtained from csi processing
        fileName 
        fileText
        classifier % Classifier for predicting observations
        server % TCPServer class object
        client
        serverStatus %Current server status 
    end
    
    methods (Access = private)
        
        function refreshTable(app)
            %Load dataset to uitable
            table = array2table(app.dataset);
            app.UITable.ColumnName = [];
            app.UITable.Data = table; 
        end
        
        function handleMessages(app)
             while app.serverStatus == 1
                 try
                    field_len = fread(app.server.connection, 1, 'uint16');
                catch
                    warning(s);
                    app.statusField.Value = 'Timeout, please restart the server';
                    app.closeServer(); 
                end 
             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.serverStatus = 0; %Server down
        end

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
            %Open file explorer and save file path
            [app.fileName,path] = uigetfile({'*.dat';'*.csv'},'Choose CSI data File');
            app.csiFile = strcat(path,app.fileName);          
            %Identify which type of file is (.dat=1 or .csv=2)
            app.fileText = strsplit(app.fileName,'.');
            textType = app.fileText{length(app.fileText)};
            if strcmp(textType,'dat')
                app.fileType = 1; 
                app.loadFileField.Value = app.csiFile;
                app.processFileButton.Enable = true;
            else
                if strcmp(textType,'csv')
                    app.fileType = 2; 
                    app.loadFileField.Value = app.csiFile;
                    app.processFileButton.Enable = true;
                else 
                    errordlg('Not a valid type of file!','File Error')
                end
            end
        end

        % Button pushed function: processFileButton
        function processFileButtonPushed(app, event)
            %Get values from EditTexts
            app.sampleFrequency = app.sampleFrequencyField.Value;
            app.windowLength = app.windowLengthField.Value; 
            app.bandpassOrder = app.orderField.Value;
            app.lowCutOff = app.lowCutoffField.Value; 
            app.highCutOff = app.highCutoffField.Value; 
            app.hampelSize = app.windowSizeField.Value;
            app.hampelThreshold = app.thresholdField.Value; 
            app.polDegree = app.degreeField.Value; 
            app.sgSize = app.windowSizeSGField.Value; 
            %Create Calibrator and FeatureExtractor objects
            %by default it works with only 10 subcarriers
            try
                app.dataCalibrator = Calibrator(app.lowCutOff,app.highCutOff,app.bandpassOrder,...
                    app.sampleFrequency,app.polDegree,app.sgSize,app.hampelSize,...
                    app.hampelThreshold,10); 
                app.featureExtractor = FeatureExtractor(app.sampleFrequency); %Call getDataset from static function of csiDataFileHandler
                app.dataset =  CSIDataFileHandler.getDataset(app.csiFile,app.fileType,app.windowLength,...
                    app.sampleFrequency,app.dataCalibrator,app.featureExtractor);
                app.refreshTable();
                app.predictButton.Enable = true; 
                app.saveButton.Enable = true; 
            catch ME
                errordlg(ME.message,'Error');
                app.predictButton.Enable = false; 
                app.saveButton.Enable = false; 
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            %When save button is pushed, a file explorer window opens for
            %naming the file and selecting where the generated table will
            %be loaded as a csv file
            dirPath = uigetdir();
            csvFileName = strcat(dirPath,'\',app.fileText{1},'_dataset.csv');
            writematrix(app.dataset,csvFileName);
        end

        % Button pushed function: predictButton
        function predictButtonPushed(app, event)
            %When this button is pushed, file explorer is loaded in order
            %to load a classifier model. Once selected, it predicts the
            %current observations in table and adds a predicted class label
            app.processFileButton.Enable = false;
            app.saveButton.Enable = false; 
            app.predictButton.Enable = false; 
            [modelName,path] = uigetfile({'*.mat'},'Choose a Model');
            modelFile = strcat(path,modelName); 
            model = load(modelFile);
            app.classifier = Classifier(model);
            labeledObservations = CSIDataFileHandler.getPrediction(app.dataset,app.classifier);
            app.dataset = labeledObservations;
            app.refreshTable();
            app.processFileButton.Enable = true;
            app.saveButton.Enable = true; 
            app.predictButton.Enable = true; 
        end

        % Button pushed function: startServerButton
        function startServerButtonPushed(app, event)
            %When button is pushed, it will check first if server is up or
            %not
            if app.serverStatus == 0 %Start the server
                app.server = TCPServer('0.0.0.0',8090,60);
                app.serverStatus = 1; 
                app.startServerButton.Text = "Close Server";
                app.statusField.Value = 'Waiting for client...';
                app.startServerButton.Enable = "off"; 
                app.serverField.Value = app.server.getMyIP(); 
                drawnow();
                app.server.startServer(); 
                app.startServerButton.Enable = "on";
                statusText = sprintf("Connected with %s",app.server.connection.RemoteHost);
                app.statusField.Value = statusText;
                %app.handleMessages(); 
            else %Close the server
                app.serverStatus = 0;  
                app.startServerButton.Text = "Start Server";
                app.startServerButton.Enable = "off";
                drawnow();
                app.server.closeServer(); 
                app.statusField.Value= "Server closed";
                app.startServerButton.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.FromFileTab = uitab(app.TabGroup);
            app.FromFileTab.Title = 'From File';
            app.GridLayout2 = uigridlayout(app.FromFileTab);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SelectFileandFilterParametersPanel = uipanel(app.GridLayout2);
            app.SelectFileandFilterParametersPanel.Title = 'Select File and Filter Parameters';
            app.LoadfileEditFieldLabel = uilabel(app.SelectFileandFilterParametersPanel);
            app.LoadfileEditFieldLabel.Text = 'Load file:';
            app.loadFileField = uieditfield(app.SelectFileandFilterParametersPanel, 'text');
            app.loadFileField.Editable = 'off';
            app.browseButton = uibutton(app.SelectFileandFilterParametersPanel, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'Browse';
            app.FilteringParametersLabel = uilabel(app.SelectFileandFilterParametersPanel);
            app.FilteringParametersLabel.Text = 'Filtering Parameters:';
            app.BandpassFilterLabel = uilabel(app.SelectFileandFilterParametersPanel);
            app.BandpassFilterLabel.Text = 'Bandpass Filter';
            app.HampelFilterLabel = uilabel(app.SelectFileandFilterParametersPanel);
            app.HampelFilterLabel.Text = 'Hampel Filter';
            app.SavitzkyGolayFilterLabel = uilabel(app.SelectFileandFilterParametersPanel);
            app.SavitzkyGolayFilterLabel.Text = 'Savitzky - Golay Filter';
            app.SampleFrequencyLabel = uilabel(app.SelectFileandFilterParametersPanel);
            app.SampleFrequencyLabel.Text = 'Sample Frequency:';
            app.sampleFrequencyField = uieditfield(app.SelectFileandFilterParametersPanel, 'numeric');
            app.sampleFrequencyField.Tooltip = {'Sample frequency of CSI data defined on Linux CSI Tool (in Hz)'};
            app.sampleFrequencyField.Value = 25;
            app.WindowLengthLabel = uilabel(app.SelectFileandFilterParametersPanel);
            app.WindowLengthLabel.Text = 'Window Length:';
            app.windowLengthField = uieditfield(app.SelectFileandFilterParametersPanel, 'numeric');
            app.windowLengthField.Tooltip = {'Number of entries taken for building each observation (in seconds)'};
            app.windowLengthField.Value = 40;
            app.OrderEditFieldLabel_2 = uilabel(app.SelectFileandFilterParametersPanel);
            app.OrderEditFieldLabel_2.Text = 'Order:';
            app.orderField = uieditfield(app.SelectFileandFilterParametersPanel, 'numeric');
            app.orderField.Tooltip = {'Bandpass filter order'};
            app.orderField.Value = 6;
            app.LowCutoffEditFieldLabel_2 = uilabel(app.SelectFileandFilterParametersPanel);
            app.LowCutoffEditFieldLabel_2.Text = 'Low Cut-off:';
            app.lowCutoffField = uieditfield(app.SelectFileandFilterParametersPanel, 'numeric');
            app.lowCutoffField.Tooltip = {'Low cut-off frequency of bandpass filter'};
            app.lowCutoffField.Value = 0.1;
            app.HighCutoffLabel = uilabel(app.SelectFileandFilterParametersPanel);
            app.HighCutoffLabel.Text = 'High Cut-off:';
            app.highCutoffField = uieditfield(app.SelectFileandFilterParametersPanel, 'numeric');
            app.highCutoffField.Tooltip = {'High cut-off frequency of bandpass filter'};
            app.highCutoffField.Value = 0.4;
            app.WindowSizeEditFieldLabel_2 = uilabel(app.SelectFileandFilterParametersPanel);
            app.WindowSizeEditFieldLabel_2.Text = 'Window Size:';
            app.windowSizeField = uieditfield(app.SelectFileandFilterParametersPanel, 'numeric');
            app.windowSizeField.Tooltip = {'Number of samples to take for each window'};
            app.windowSizeField.Value = 200;
            app.ThresholdLabel = uilabel(app.SelectFileandFilterParametersPanel);
            app.ThresholdLabel.Text = 'Threshold:';
            app.thresholdField = uieditfield(app.SelectFileandFilterParametersPanel, 'numeric');
            app.thresholdField.Tooltip = {'Hampel threshold value'};
            app.thresholdField.Value = 2;
            app.PolynomialDegreeLabel = uilabel(app.SelectFileandFilterParametersPanel);
            app.PolynomialDegreeLabel.Text = 'Polynomial Degree:';
            app.degreeField = uieditfield(app.SelectFileandFilterParametersPanel, 'numeric');
            app.degreeField.Tooltip = {'Polynomial degree of the SG filter'};
            app.degreeField.Value = 3;
            app.WindowSizeLabel = uilabel(app.SelectFileandFilterParametersPanel);
            app.WindowSizeLabel.Text = 'Window Size:';
            app.windowSizeSGField = uieditfield(app.SelectFileandFilterParametersPanel, 'numeric');
            app.windowSizeSGField.Tooltip = {'Number of samples for each window of SG filter'};
            app.windowSizeSGField.Value = 51;
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.processFileButton = uibutton(app.GridLayout3, 'push');
            app.processFileButton.ButtonPushedFcn = createCallbackFcn(app, @processFileButtonPushed, true);
            app.processFileButton.Enable = 'off';
            app.processFileButton.Text = 'Process File';
            app.predictButton = uibutton(app.GridLayout3, 'push');
            app.predictButton.ButtonPushedFcn = createCallbackFcn(app, @predictButtonPushed, true);
            app.predictButton.Enable = 'off';
            app.predictButton.Text = 'Predict!';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.saveButton = uibutton(app.GridLayout4, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Enable = 'off';
            app.saveButton.Text = 'Save CSV';
            app.RealTimeMonitorTab = uitab(app.TabGroup);
            app.RealTimeMonitorTab.Title = 'Real Time Monitor';
            app.Image = uiimage(app.RealTimeMonitorTab);
            app.Image.ImageSource = 'bi_icon_breath.png';
            app.BPMLabel = uilabel(app.RealTimeMonitorTab);
            app.BPMLabel.Text = '0 BPM';
            app.UniversidadAutnomadeBajaCaliforniaInstitutodeIngenieraLabel = uilabel(app.RealTimeMonitorTab);
            app.UniversidadAutnomadeBajaCaliforniaInstitutodeIngenieraLabel.Text = 'Universidad Autónoma de Baja California - Instituto de Ingeniería';
            app.startServerButton = uibutton(app.RealTimeMonitorTab, 'push');
            app.startServerButton.ButtonPushedFcn = createCallbackFcn(app, @startServerButtonPushed, true);
            app.startServerButton.Text = 'Start Server';
            app.ServerAddressEditFieldLabel = uilabel(app.RealTimeMonitorTab);
            app.ServerAddressEditFieldLabel.Text = 'Server Address';
            app.serverField = uieditfield(app.RealTimeMonitorTab, 'text');
            app.serverField.Editable = 'off';
            app.ServerStatusEditFieldLabel = uilabel(app.RealTimeMonitorTab);
            app.ServerStatusEditFieldLabel.Text = 'Server Status';
            app.statusField = uieditfield(app.RealTimeMonitorTab, 'text');
            app.statusField.Editable = 'off';
            app.UIAxes = uiaxes(app.RealTimeMonitorTab);
            xlabel(app.UIAxes, 'Index')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSIVitalSignMonitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end